classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        TabGroup               matlab.ui.container.TabGroup       % Setting...
        Tab                    matlab.ui.container.Tab            % Settings
        status_window_output   matlab.ui.control.CheckBox         % Show st...
        command_window_output  matlab.ui.control.CheckBox         % Show co...
        session_report         matlab.ui.control.CheckBox         % Print S...
        LabelDropDown          matlab.ui.control.Label            % Transpo...
        Transport_Protocol     matlab.ui.control.DropDown         % tcp, ud...
        Label2                 matlab.ui.control.Label            % PMU/PDC...
        LabelEditField2        matlab.ui.control.Label            % PMU/PDC...
        PMU_IP_Address         matlab.ui.control.EditField       
        LabelNumericEditField  matlab.ui.control.Label            % PMU/PDC...
        PMU_port_number        matlab.ui.control.NumericEditField % [1000 9...
        Label3                 matlab.ui.control.Label            % Simulat...
        LabelNumericEditField2 matlab.ui.control.Label            % Max. Si...
        SimulationTime         matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % PMU/PDC...
        PMU_ID_value           matlab.ui.control.NumericEditField % [0 Inf]
        LabelDropDown2         matlab.ui.control.Label            % C37.118...
        C37118_Protocol        matlab.ui.control.DropDown         % C37.118...
        Button                 matlab.ui.control.Button           % Button
        Tab2                   matlab.ui.container.Tab            % Power C...
        Label                  matlab.ui.control.Label            % SYNCHRO...
    end

    
    properties (Access = public)
        com_mode = true;
        protocol = 'udp';
        conf_fr_ver = 0;
        ip_address_primary = '0.0.0.0';
        port_primary = 1111;
        device_id = 10;
        verbose_info = false;
        verbose_debug = false;
        session_report1 = false;
        sim_time = 100;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all
            app.com_mode = true;
        end

        % Transport_Protocol value changed function
        function Transport_ProtocolValueChanged(app, event)
            value = app.Transport_Protocol.Value;
            app.protocol = value;
        end

        % C37118_Protocol value changed function
        function C37118_ProtocolValueChanged(app, event)
            value = app.C37118_Protocol.Value;
            if strcmp(value,'C37.118 - 2005')
                app.conf_fr_ver = 2;
            else
                app.conf_fr_ver = 3;
            end
        end

        % PMU_IP_Address value changed function
        function PMU_IP_AddressValueChanged(app)
            value = app.PMU_IP_Address.Value;
            app.ip_address_primary = value;
        end

        % PMU_port_number value changed function
        function PMU_port_numberValueChanged(app)
            value = app.PMU_port_number.Value;
            app.port_primary = value;
        end

        % PMU_ID_value value changed function
        function PMU_ID_valueValueChanged(app)
            value = app.PMU_ID_value.Value;
            app.device_id = value;
        end

        % status_window_output value changed function
        function status_window_outputValueChanged(app)
            value = app.status_window_output.Value;
            app.verbose_info = value;
        end

        % command_window_output value changed function
        function command_window_outputValueChanged(app)
            value = app.command_window_output.Value;
            app.verbose_debug = value;
        end

        % session_report value changed function
        function session_reportValueChanged(app)
            value = app.session_report.Value;
            app.session_report1 = value;
        end

        % SimulationTime value changed function
        function SimulationTimeValueChanged(app)
            value = app.SimulationTime.Value;
            app.sim_time = value;
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            
            Com_mode = app.com_mode;
            Protocol = app.protocol;
            Conf_fr_ver = app.conf_fr_ver;
            Ip_address_primary = app.ip_address_primary;
            Port_primary = app.port_primary;
            Device_id = app.device_id;
            Verbose_info = app.verbose_info;
            Verbose_debug = app.verbose_debug;
            Session_report1 = app.session_report1;
            Sim_time = app.sim_time;
            
            save('TEMPSADF.mat','Com_mode','Protocol','Conf_fr_ver','Ip_address_primary','Port_primary','Device_id','Verbose_info','Verbose_debug','Session_report1','Sim_time');

            SADF_run();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Settings';
            app.status_window_output = uicheckbox(app.Tab);
            app.status_window_output.ValueChangedFcn = createCallbackFcn(app, @status_window_outputValueChanged);
            app.status_window_output.Text = 'Show status window output (SADF)';
            app.command_window_output = uicheckbox(app.Tab);
            app.command_window_output.ValueChangedFcn = createCallbackFcn(app, @command_window_outputValueChanged);
            app.command_window_output.Text = 'Show command window output (PMU Messages)';
            app.session_report = uicheckbox(app.Tab);
            app.session_report.ValueChangedFcn = createCallbackFcn(app, @session_reportValueChanged);
            app.session_report.Text = 'Print SADF session report';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'Transport Protocol';
            app.Transport_Protocol = uidropdown(app.Tab);
            app.Transport_Protocol.Items = {'tcp', 'udp', '-select-'};
            app.Transport_Protocol.ValueChangedFcn = createCallbackFcn(app, @Transport_ProtocolValueChanged, true);
            app.Transport_Protocol.Value = '-select-';
            app.Label2 = uilabel(app.Tab);
            app.Label2.Text = 'PMU/PDC Connection Settings';
            app.LabelEditField2 = uilabel(app.Tab);
            app.LabelEditField2.Text = 'PMU/PDC IP Address';
            app.PMU_IP_Address = uieditfield(app.Tab, 'text');
            app.PMU_IP_Address.ValueChangedFcn = createCallbackFcn(app, @PMU_IP_AddressValueChanged);
            app.LabelNumericEditField = uilabel(app.Tab);
            app.LabelNumericEditField.Text = 'PMU/PDC port number';
            app.PMU_port_number = uieditfield(app.Tab, 'numeric');
            app.PMU_port_number.ValueChangedFcn = createCallbackFcn(app, @PMU_port_numberValueChanged);
            app.PMU_port_number.Limits = [1000 9999];
            app.PMU_port_number.Value = 1000;
            app.Label3 = uilabel(app.Tab);
            app.Label3.Text = 'Simulation Settings';
            app.LabelNumericEditField2 = uilabel(app.Tab);
            app.LabelNumericEditField2.Text = 'Max. Simulation Time (seconds)';
            app.SimulationTime = uieditfield(app.Tab, 'numeric');
            app.SimulationTime.ValueChangedFcn = createCallbackFcn(app, @SimulationTimeValueChanged);
            app.SimulationTime.Limits = [0 Inf];
            app.LabelNumericEditField3 = uilabel(app.Tab);
            app.LabelNumericEditField3.Text = 'PMU/PDC ID value';
            app.PMU_ID_value = uieditfield(app.Tab, 'numeric');
            app.PMU_ID_value.ValueChangedFcn = createCallbackFcn(app, @PMU_ID_valueValueChanged);
            app.PMU_ID_value.Limits = [0 Inf];
            app.LabelDropDown2 = uilabel(app.Tab);
            app.LabelDropDown2.Text = 'C37.118 Protocol';
            app.C37118_Protocol = uidropdown(app.Tab);
            app.C37118_Protocol.Items = {'C37.118 - 2005', 'C37.118 - 2011', '-select-'};
            app.C37118_Protocol.ValueChangedFcn = createCallbackFcn(app, @C37118_ProtocolValueChanged, true);
            app.C37118_Protocol.Value = '-select-';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Power Calculations';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'SYNCHROPHASOR APPLICATION DEVELOPMENT FRAMEWORK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end